classdef FOSWEC2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CurrentLimitSpinner           matlab.ui.control.Spinner
        CurrentLimitSpinnerLabel      matlab.ui.control.Label
        BowReferenceDropDown          matlab.ui.control.DropDown
        BowReferenceDropDownLabel     matlab.ui.control.Label
        HWRLShareButton               matlab.ui.control.Button
        ExperimentEditField           matlab.ui.control.EditField
        ExperimentEditFieldLabel      matlab.ui.control.Label
        ProjectEditField              matlab.ui.control.EditField
        ProjectEditFieldLabel         matlab.ui.control.Label
        TrialSpinner                  matlab.ui.control.Spinner
        TrialLabel                    matlab.ui.control.Label
        AftReferenceDropDown          matlab.ui.control.DropDown
        ReferenceLabel                matlab.ui.control.Label
        SystemLog                     slrealtime.ui.control.SystemLog
        StartStopButton               matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        controlWordAftEditField       matlab.ui.control.NumericEditField
        controlWordAftEditFieldLabel  matlab.ui.control.Label
        statusWordBowEditField        matlab.ui.control.NumericEditField
        statusWordBowEditFieldLabel   matlab.ui.control.Label
        statusWordAftEditField        matlab.ui.control.NumericEditField
        statusWordAftEditFieldLabel   matlab.ui.control.Label
        eCATstateEditField            matlab.ui.control.NumericEditField
        eCATstateEditFieldLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        buildDir
        mdlName = evalin('base','mdlName');

        Instrument
        InstrumentManager
        tg
        tgName

        startIcon = fullfile('icons','slrtRunIcon.png')
        stopIcon = fullfile('icons','slrtStopIcon.png')
    end

    methods (Access = private)

        function setupInstrumentation(app)
            app.Instrument = slrealtime.Instrument;
            app.Instrument.connectLine(app.UIAxes, [app.mdlName,'/aftCurrent'],1);
            app.Instrument.connectLine(app.UIAxes, [app.mdlName,'/bowCurrent'],1);

            app.Instrument.connectScalar(app.eCATstateEditField,[app.mdlName,'/Initialization/eCATstate'],1)
            app.Instrument.connectScalar(app.statusWordAftEditField,[app.mdlName,'/Initialization/statusWordAft'],1)
%             app.Instrument.connectScalar(app.statusWordBowEditField,[app.mdlName,'/Initialization/statusWordBow'],1)
            app.Instrument.connectScalar(app.controlWordAftEditField,[app.mdlName,'/Initialization/controlWordAft'],1)

            app.InstrumentManager = slrealtime.ui.tool.InstrumentManager(app.UIFigure);
            app.InstrumentManager.Instruments = app.Instrument;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tgName = evalin('base','tgName');
            app.tg = slrealtime(app.tgName);

            app.tg.stop;
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start';

            app.HWRLShareButton.Text = 'Push to Share';
            app.HWRLShareButton.BackgroundColor = 'Green';
            assignin('base','shareStatus','yes')

            app.setupInstrumentation;
        end

        % Button pushed function: StartStopButton
        function StartStopButtonButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m');
            end
        end

        % Value changed function: AftReferenceDropDown
        function AftReferenceDropDownValueChanged(app, event)
            value = app.AftReferenceDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',3)
            elseif strcmp(value,'WN1')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',4)
            elseif strcmp(value,'WN2')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',5)
            elseif strcmp(value,'WN3')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',7)
            end
            
        end

        % Button pushed function: HWRLShareButton
        function HWRLShareButtonPushed(app, event)
            if strcmp(app.HWRLShareButton.Text,'Push to Share')
                app.HWRLShareButton.Text = 'No Push to Share';
                app.HWRLShareButton.BackgroundColor = 'Red';
                assignin('base','shareStatus','no')
            else
                app.HWRLShareButton.Text = 'Push to Share';
                app.HWRLShareButton.BackgroundColor = 'Green';
                assignin('base','shareStatus','yes')
            end            
        end

        % Value changed function: BowReferenceDropDown
        function BowReferenceDropDownValueChanged(app, event)
            value = app.AftReferenceDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',3)
            elseif strcmp(value,'WN1')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',4)
            elseif strcmp(value,'WN2')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',5)
            elseif strcmp(value,'WN3')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/inputSwitch1'],'Value',7)
            end
            
        end

        % Value changed function: CurrentLimitSpinner
        function CurrentLimitSpinnerValueChanged(app, event)
            value = app.CurrentLimitSpinner.Value;
            app.tg.setparam([app.mdlName,'/aftCurrentLimit'],'Value',value)
            app.tg.setparam([app.mdlName,'/bowCurrentLimit'],'Value',value)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartStopButton = uibutton(app.LeftPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonButtonPushed, true);
            app.StartStopButton.Text = 'StartStop';
            app.SystemLog = slrealtime.ui.control.SystemLog(app.LeftPanel);
            app.ReferenceLabel = uilabel(app.LeftPanel);
            app.ReferenceLabel.Text = 'Aft Reference';
            app.AftReferenceDropDown = uidropdown(app.LeftPanel);
            app.AftReferenceDropDown.Items = {'None', 'Ramps', 'Sine', 'WN1', 'WN2', 'WN3', 'Chirp'};
            app.AftReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @AftReferenceDropDownValueChanged, true);
            app.AftReferenceDropDown.Value = 'None';
            app.TrialLabel = uilabel(app.LeftPanel);
            app.TrialLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.LeftPanel);
            app.TrialSpinner.Limits = [0 Inf];
            app.TrialSpinner.RoundFractionalValues = 'on';
            app.TrialSpinner.Value = 1;
            app.ProjectEditFieldLabel = uilabel(app.LeftPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.LeftPanel, 'text');
            app.ProjectEditField.Editable = 'off';
            app.ProjectEditField.Value = 'FOSWEC2';
            app.ExperimentEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.LeftPanel, 'text');
            app.HWRLShareButton = uibutton(app.LeftPanel, 'push');
            app.HWRLShareButton.ButtonPushedFcn = createCallbackFcn(app, @HWRLShareButtonPushed, true);
            app.HWRLShareButton.Text = 'HWRL Share';
            app.BowReferenceDropDownLabel = uilabel(app.LeftPanel);
            app.BowReferenceDropDownLabel.Text = 'Bow Reference';
            app.BowReferenceDropDown = uidropdown(app.LeftPanel);
            app.BowReferenceDropDown.Items = {'None', 'Ramps', 'Sine', 'WN1', 'WN2', 'WN3', 'Chirp'};
            app.BowReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @BowReferenceDropDownValueChanged, true);
            app.BowReferenceDropDown.Value = 'None';
            app.CurrentLimitSpinnerLabel = uilabel(app.LeftPanel);
            app.CurrentLimitSpinnerLabel.Text = 'Current Limit';
            app.CurrentLimitSpinner = uispinner(app.LeftPanel);
            app.CurrentLimitSpinner.Limits = [0 40];
            app.CurrentLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentLimitSpinnerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.eCATstateEditFieldLabel = uilabel(app.RightPanel);
            app.eCATstateEditFieldLabel.Text = 'eCATstate';
            app.eCATstateEditField = uieditfield(app.RightPanel, 'numeric');
            app.statusWordAftEditFieldLabel = uilabel(app.RightPanel);
            app.statusWordAftEditFieldLabel.Text = 'statusWordAft';
            app.statusWordAftEditField = uieditfield(app.RightPanel, 'numeric');
            app.statusWordBowEditFieldLabel = uilabel(app.RightPanel);
            app.statusWordBowEditFieldLabel.Text = 'statusWordBow';
            app.statusWordBowEditField = uieditfield(app.RightPanel, 'numeric');
            app.controlWordAftEditFieldLabel = uilabel(app.RightPanel);
            app.controlWordAftEditFieldLabel.Text = 'controlWordAft';
            app.controlWordAftEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FOSWEC2app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end